classdef rcsDataChopper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RCSDataChopperUIFigure  matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        EventViewTab            matlab.ui.container.Tab
        loaddatadirButton       matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        filestartLabel          matlab.ui.control.Label
        fileStartReport         matlab.ui.control.Label
        fileendLabel            matlab.ui.control.Label
        fileEndReport           matlab.ui.control.Label
        durationLabel           matlab.ui.control.Label
        fileDurationReport      matlab.ui.control.Label
        DataViewTab             matlab.ui.container.Tab
        FromDropDownLabel       matlab.ui.control.Label
        FromDropDown            matlab.ui.control.DropDown
        ToDropDownLabel         matlab.ui.control.Label
        ToDropDown              matlab.ui.control.DropDown
        FromEditFieldLabel      matlab.ui.control.Label
        FromEditField           matlab.ui.control.EditField
        ToEditFieldLabel        matlab.ui.control.Label
        ToEditField             matlab.ui.control.EditField
        saveButton              matlab.ui.control.Button
        savenameEditFieldLabel  matlab.ui.control.Label
        savenameEditField       matlab.ui.control.EditField
        DataPlot                matlab.ui.container.TabGroup
        RawDataTab              matlab.ui.container.Tab
        popoutRawData           matlab.ui.control.Button
        PSDTab                  matlab.ui.container.Tab
        popOutPSD               matlab.ui.control.Button
        clearPSD                matlab.ui.control.Button
        SpectrogramTab          matlab.ui.container.Tab
        popOutSpectrakData      matlab.ui.control.Button
        zoomoutButton           matlab.ui.control.Button
        plotspectrogramButton   matlab.ui.control.Button
        lblstructDropDownLabel  matlab.ui.control.Label
        lblstructDropDown       matlab.ui.control.DropDown
        printlablestrucButton   matlab.ui.control.Button
        plotPSDButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        hfig % handle to punched out figure
        diruse % Description
        outdatcomplete % raw data
        outRec % device time domain data
        eventTable % event table data
        displayTable % display table data
        limitFrom % what to draw limits from (datetime)
        limitTo % what to draw limits to (datetime)
        fileStart % Description
        fileEnd % Description
        lblStrucIdx % idx of lsbel structure
        tdDat % table of time domain data
        patientStr % patient name 
        sideStr % patient side 
    end
    
    methods (Access = public)
        
        function updateFig(app)
            %% load data
            [app.outdatcomplete,app.outRec,app.eventTable] =  MAIN_load_rcs_data_from_folder(app.diruse);
            nm = app.RCSDataChopperUIFigure.Name;
            [pn,fn] = fileparts(app.diruse);
            [pn,fn] = fileparts(pn);
            [pn,patientraw] = fileparts(pn);
            app.patientStr = patientraw(1:end-1);
            app.sideStr = patientraw(end);
            t = app.eventTable.HostUnixTime(1);
            t.Format = 'dd-MMM-yyyy HH:mm';
            appTitle = sprintf('%s %s %s session - %s',app.patientStr,app.sideStr,t,app.eventTable.sessionid{1});
            app.RCSDataChopperUIFigure.Name = appTitle;
            %% populate table
            app.displayTable = app.eventTable(:,{'UnixOnsetTime','EventSubType','EventType'});
            app.UITable.Data = app.displayTable;
            app.UITable.ColumnName = app.displayTable.Properties.VariableNames;
            %% populate drop down menu
            app.tdDat = struct2table(app.outRec,'asarray',true);
            app.lblstructDropDown.Items = cellstr(datestr(app.tdDat.timeStart));
            %% populate time domain data
            startTime = app.outdatcomplete.derivedTimes(1);
            endTime   = app.outdatcomplete.derivedTimes(end);
            app.fileStart = datestr(startTime);
            app.fileEnd = datestr(endTime);
            app.fileStartReport.Text =  datestr(startTime);
            app.fileEndReport.Text =  datestr(endTime);
            struse = sprintf('%s (hh:mm.ss)',datestr(endTime- startTime,'HH:MM.SS'));
            app.fileDurationReport.Text = struse;
            etyps = app.displayTable.EventSubType;
            if ~iscell(etyps)
                etyps = {etyps};
            end
            etyps(cellfun(@(x) ~ischar(x),etyps)) = '' % in case not all strings and have doubles 
            app.FromDropDown.Items = etyps;
            app.FromDropDown.ItemsData = 1:length( etyps);
            app.FromDropDown.Value = 1;
            app.ToDropDown.Items = etyps;
            app.ToDropDown.ItemsData = 1:length( etyps);
            app.ToDropDown.Value = length(etyps);
            % set up limits to include all data initially
            app.FromEditField.Value = app.fileStart;
            app.ToEditField.Value = app.fileEnd;
        end
        
        function updatePlot(app)
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            timesuse = app.outdatcomplete.derivedTimes(idxuse);
            val = app.lblstructDropDown.Value ;
            itms = app.lblstructDropDown.Items;
            outrcidx = find(cellfun(@(x) strcmp(val,x),itms)==1);
            for c = 1:4
                hsub(c) = subplot(4,1,c,'Parent',app.RawDataTab);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                plot(hsub(c),timesuse,y);
                title(hsub(c),app.outRec(outrcidx).tdData(c).chanFullStr);
            end
        end
        
        function plotSpectrogram(app)
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            times = app.outdatcomplete.derivedTimes(idxuse);
            for c = 1:4
                hsub(c) = subplot(4,1,c,'Parent',app.SpectrogramTab);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                srate = str2num(strrep(app.outRec(1).tdData(c).sampleRate,'Hz',''));
                [s,f,t,p] = spectrogram(y,srate,ceil(0.8750*srate),1:120,srate,...
                    'yaxis','power');
                
                surf(hsub(c),seconds(t), f, 10*log10(p), 'EdgeColor', 'none');
                shading(hsub(c),'interp');
                view(hsub(c),2);
                axis(hsub(c),'tight');
                xlabel(hsub(c),'seconds');
                ylabel(hsub(c),'Frequency (Hz)');
                title(hsub(c),app.outRec(1).tdData(c).chanFullStr);
            end
            
        end
    
        function plotPSD(app)
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            times = app.outdatcomplete.derivedTimes(idxuse);
            if sum(ishghandle(app.PSDTab.Children,'axes')) == 4 
                doNotOverwrite = 1; 
            else 
                doNotOverwrite = 0; 
            end
            allHandles = app.PSDTab.Children;
            axesHandles = app.PSDTab.Children(ishghandle(app.PSDTab.Children,'axes'));
            for c = 1:4
                if doNotOverwrite
                    hsub(c) = axesHandles(c);
                else
                    hsub(c) = subplot(2,2,c,'Parent',app.PSDTab);
                end
                hold(hsub(c),'on');
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                srate = str2num(strrep(app.outRec(1).tdData(c).sampleRate,'Hz',''));
                [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                hplt = plot(hsub(c),f,log10(fftOut));
                hplt.LineWidth = 2;
                
                xlabel(hsub(c),'Frequency (Hz)');
                ylabel(hsub(c),'Power (log_1_0\muV^2/Hz)');
                title(hsub(c),app.outRec(1).tdData(c).chanFullStr);
            end
        end
  
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            var = varargin{1}; 
            if isstruct(var)
                params = var;
                if ~isempty(params)
                    app.diruse = params.dir;
                    updateFig(app);
                    updatePlot(app);
                end
            else
                app.diruse = varargin{1};
                updateFig(app);
                updatePlot(app);
            end
        end

        % Value changed function: FromDropDown
        function FromDropDownValueChanged(app, event)
            value = app.FromDropDown.Value;
            eventidx = app.FromDropDown.Value;
            disTab = app.displayTable;
            app.limitFrom = disTab.UnixOnsetTime(eventidx);
            app.FromEditField.Value = datestr(app.limitFrom);
            updatePlot(app);
            
        end

        % Value changed function: ToDropDown
        function ToDropDownValueChanged(app, event)
            value = app.ToDropDown.Value;
            eventidx = app.ToDropDown.Value;
            disTab = app.displayTable;
            app.limitTo = disTab.UnixOnsetTime(eventidx);
            app.ToEditField.Value = datestr(app.limitTo);
            updatePlot(app);
        end

        % Button pushed function: zoomoutButton
        function zoomoutButtonPushed(app, event)
            app.ToEditField.Value = app.fileEnd;
            app.FromEditField.Value = app.fileStart;
            updatePlot(app);
        end

        % Value changed function: ToEditField
        function ToEditFieldValueChanged(app, event)
            value = app.ToEditField.Value;
            updatePlot(app);
        end

        % Value changed function: FromEditField
        function FromEditFieldValueChanged(app, event)
            value = app.FromEditField.Value;
            updatePlot(app);
        end

        % Button pushed function: plotspectrogramButton
        function plotspectrogramButtonPushed(app, event)
            plotSpectrogram(app);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            times = app.outdatcomplete.derivedTimes;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            outdatachunk = app.outdatcomplete(idxuse,:);
            eventTable = app.eventTable;
            outRec = app.outRec;
            fnm = sprintf('%s.mat',app.savenameEditField.Value);
            svname = fullfile(app.diruse,fnm);
            save(svname,'outdatachunk','eventTable','outRec');
        end

        % Button pushed function: loaddatadirButton
        function loaddatadirButtonPushed(app, event)
            app.diruse = uigetdir('choose dir with .jsons');
            updateFig(app);
            updatePlot(app);
        end

        % Button pushed function: printlablestrucButton
        function printlablestrucButtonPushed(app, event)
            struct2table(app.outRec)
        end

        % Value changed function: lblstructDropDown
        function lblstructDropDownValueChanged(app, event)
            value = app.lblstructDropDown.Value;
            updatePlot(app);
        end

        % Button pushed function: popoutRawData
        function popoutRawDataButtonPushed(app, event)
            hfig = figure;
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            timesuse = app.outdatcomplete.derivedTimes(idxuse);
            val = app.lblstructDropDown.Value ;
            itms = app.lblstructDropDown.Items;
            outrcidx = find(cellfun(@(x) strcmp(val,x),itms)==1);
            for c = 1:4
                hsub(c) = subplot(4,1,c,'Parent',hfig);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                plot(hsub(c),timesuse,y);
                title(hsub(c),app.outRec(outrcidx).tdData(c).chanFullStr);
            end
            linkaxes(hsub,'x');
        end

        % Button pushed function: popOutSpectrakData
        function popOutSpectrakDataButtonPushed(app, event)
            hfig = figure;
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            times = app.outdatcomplete.derivedTimes(idxuse);
            for c = 1:4
                hsub(c) = subplot(4,1,c,'Parent',hfig);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                srate = str2num(strrep(app.outRec(1).tdData(c).sampleRate,'Hz',''));
                [s,f,t,p] = spectrogram(y,srate,ceil(0.8750*srate),1:120,srate,...
                    'yaxis','power');
                
                surf(hsub(c),seconds(t), f, 10*log10(p), 'EdgeColor', 'none');
                shading(hsub(c),'interp');
                view(hsub(c),2);
                axis(hsub(c),'tight');
                xlabel(hsub(c),'seconds');
                ylabel(hsub(c),'Frequency (Hz)');
                title(hsub(c),app.outRec(1).tdData(c).chanFullStr);
            end
            linkaxes(hsub,'x');
        end

        % Button pushed function: plotPSDButton
        function plotPSDButtonPushed(app, event)
            plotPSD(app); 
        end

        % Button pushed function: popOutPSD
        function popOutPSDButtonPushed(app, event)
            hfig = figure; 
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            times = app.outdatcomplete.derivedTimes(idxuse);
            for c = 1:4
                hsub(c) = subplot(2,2,c,'Parent',hfig);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                srate = str2num(strrep(app.outRec(1).tdData(c).sampleRate,'Hz',''));
                [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                hplt = plot(hsub(c),f,log10(fftOut));
                hplt.LineWidth = 2;
                
                xlabel(hsub(c),'Frequency (Hz)');
                ylabel(hsub(c),'Power (log_1_0\muV^2/Hz)');
                title(hsub(c),app.outRec(1).tdData(c).chanFullStr);
            end
            linkaxes(hsub,'x');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RCSDataChopperUIFigure = uifigure('Visible', 'off');
            app.RCSDataChopperUIFigure.Name = 'RC+S Data Chopper';
            app.TabGroup = uitabgroup(app.RCSDataChopperUIFigure);
            app.EventViewTab = uitab(app.TabGroup);
            app.EventViewTab.Title = 'Event View';
            app.loaddatadirButton = uibutton(app.EventViewTab, 'push');
            app.loaddatadirButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatadirButtonPushed, true);
            app.loaddatadirButton.Tooltip = {'load data directory with .json files'};
            app.loaddatadirButton.Text = 'load data dir';
            app.UITable = uitable(app.EventViewTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.filestartLabel = uilabel(app.EventViewTab);
            app.filestartLabel.Text = 'file start:';
            app.fileStartReport = uilabel(app.EventViewTab);
            app.fileStartReport.Text = '00';
            app.fileendLabel = uilabel(app.EventViewTab);
            app.fileendLabel.Text = 'file end:';
            app.fileEndReport = uilabel(app.EventViewTab);
            app.fileEndReport.Text = '00';
            app.durationLabel = uilabel(app.EventViewTab);
            app.durationLabel.Text = 'duration:';
            app.fileDurationReport = uilabel(app.EventViewTab);
            app.fileDurationReport.Text = '00';
            app.DataViewTab = uitab(app.TabGroup);
            app.DataViewTab.Title = 'Data View';
            app.FromDropDownLabel = uilabel(app.DataViewTab);
            app.FromDropDownLabel.Text = 'From:';
            app.FromDropDown = uidropdown(app.DataViewTab);
            app.FromDropDown.ValueChangedFcn = createCallbackFcn(app, @FromDropDownValueChanged, true);
            app.ToDropDownLabel = uilabel(app.DataViewTab);
            app.ToDropDownLabel.Text = 'To:';
            app.ToDropDown = uidropdown(app.DataViewTab);
            app.ToDropDown.ValueChangedFcn = createCallbackFcn(app, @ToDropDownValueChanged, true);
            app.FromEditFieldLabel = uilabel(app.DataViewTab);
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.DataViewTab, 'text');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.ToEditFieldLabel = uilabel(app.DataViewTab);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.DataViewTab, 'text');
            app.ToEditField.ValueChangedFcn = createCallbackFcn(app, @ToEditFieldValueChanged, true);
            app.ToEditField.Tooltip = {'Change this value to adjust limits in all channels'};
            app.saveButton = uibutton(app.DataViewTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.savenameEditFieldLabel = uilabel(app.DataViewTab);
            app.savenameEditFieldLabel.Text = 'save name';
            app.savenameEditField = uieditfield(app.DataViewTab, 'text');
            app.DataPlot = uitabgroup(app.DataViewTab);
            app.RawDataTab = uitab(app.DataPlot);
            app.RawDataTab.Title = 'Raw Data';
            app.popoutRawData = uibutton(app.RawDataTab, 'push');
            app.popoutRawData.ButtonPushedFcn = createCallbackFcn(app, @popoutRawDataButtonPushed, true);
            app.popoutRawData.Text = 'pop out';
            app.PSDTab = uitab(app.DataPlot);
            app.PSDTab.Title = 'PSD';
            app.popOutPSD = uibutton(app.PSDTab, 'push');
            app.popOutPSD.ButtonPushedFcn = createCallbackFcn(app, @popOutPSDButtonPushed, true);
            app.popOutPSD.Text = 'pop out';
            app.clearPSD = uibutton(app.PSDTab, 'push');
            app.clearPSD.Text = 'clear';
            app.SpectrogramTab = uitab(app.DataPlot);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.popOutSpectrakData = uibutton(app.SpectrogramTab, 'push');
            app.popOutSpectrakData.ButtonPushedFcn = createCallbackFcn(app, @popOutSpectrakDataButtonPushed, true);
            app.popOutSpectrakData.Text = 'pop out';
            app.zoomoutButton = uibutton(app.DataViewTab, 'push');
            app.zoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @zoomoutButtonPushed, true);
            app.zoomoutButton.Text = 'zoom out';
            app.plotspectrogramButton = uibutton(app.DataViewTab, 'push');
            app.plotspectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @plotspectrogramButtonPushed, true);
            app.plotspectrogramButton.Text = 'plot spectrogram';
            app.lblstructDropDownLabel = uilabel(app.DataViewTab);
            app.lblstructDropDownLabel.Text = 'lbl struct';
            app.lblstructDropDown = uidropdown(app.DataViewTab);
            app.lblstructDropDown.ValueChangedFcn = createCallbackFcn(app, @lblstructDropDownValueChanged, true);
            app.printlablestrucButton = uibutton(app.DataViewTab, 'push');
            app.printlablestrucButton.ButtonPushedFcn = createCallbackFcn(app, @printlablestrucButtonPushed, true);
            app.printlablestrucButton.Text = 'print lable struc';
            app.plotPSDButton = uibutton(app.DataViewTab, 'push');
            app.plotPSDButton.ButtonPushedFcn = createCallbackFcn(app, @plotPSDButtonPushed, true);
            app.plotPSDButton.Text = 'plot PSD';
            % Show the figure after all components are created
            app.RCSDataChopperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rcsDataChopper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RCSDataChopperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RCSDataChopperUIFigure)
        end
    end
end