classdef rcsSessionViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        sessionsTab    matlab.ui.container.Tab
        UITable        matlab.ui.control.Table
        loaddirButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        rootdir % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loaddirButton
        function loaddirButtonPushed(app, event)
            app.rootdir = uigetdir(pwd,'dir with session files');
            tblout = getDataBaseRCSdata(app.rootdir);
            tbluse = tblout(:,{'sessname','rectime','startTime','endTime','duration'});
            app.UITable.Data = tbluse;
            app.UITable.ColumnName = tbluse.Properties.VariableNames;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            sessdir = app.UITable.Data(indices(1,1),:).sessname{:};
            ff = findFilesBVQX(fullfile(app.rootdir,sessdir),'Device*',struct('dirs',1));
            if isempty(ff)
                warning('could not find any data in this session');
            else
                diruse = ff{1};
                params.dir = diruse;
                rcsDataChopper(params);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.sessionsTab = uitab(app.TabGroup);
            app.sessionsTab.Title = 'sessions';
            app.UITable = uitable(app.sessionsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.loaddirButton = uibutton(app.sessionsTab, 'push');
            app.loaddirButton.ButtonPushedFcn = createCallbackFcn(app, @loaddirButtonPushed, true);
            app.loaddirButton.Text = 'load dir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rcsSessionViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end